classdef Kraftkurven < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KraftkurvenUIFigure            matlab.ui.Figure
        AbbildungenPanel               matlab.ui.container.Panel
        Overlay1Panel                  matlab.ui.container.Panel
        XChannelDropDownLabel          matlab.ui.control.Label
        XChannelDropDown_1             matlab.ui.control.DropDown
        YChannelDropDownLabel          matlab.ui.control.Label
        YChannelDropDown_1             matlab.ui.control.DropDown
        Overlay2Panel                  matlab.ui.container.Panel
        XChannelDropDown_2Label        matlab.ui.control.Label
        XChannelDropDown_2             matlab.ui.control.DropDown
        YChannelDropDown_2Label        matlab.ui.control.Label
        YChannelDropDown_2             matlab.ui.control.DropDown
        Overlay3Panel                  matlab.ui.container.Panel
        XChannelDropDown_3Label        matlab.ui.control.Label
        XChannelDropDown_3             matlab.ui.control.DropDown
        YChannelDropDown_3Label        matlab.ui.control.Label
        YChannelDropDown_3             matlab.ui.control.DropDown
        aktivierenButton               matlab.ui.control.StateButton
        aktivierenButton_2             matlab.ui.control.StateButton
        LadeKraftkurvenPanel           matlab.ui.container.Panel
        LoadCurvesEditField            matlab.ui.control.EditField
        LadenButton                    matlab.ui.control.Button
        KurvenlisteButton              matlab.ui.control.Button
        EinstellungenNormierungPanel   matlab.ui.container.Panel
        NormSettingsPanel              matlab.ui.container.Panel
        NormValueNEditFieldLabel       matlab.ui.control.Label
        NormValueNEditField            matlab.ui.control.NumericEditField
        PeakNumberofInterestEditFieldLabel  matlab.ui.control.Label
        PeakNumberofInterestEditField  matlab.ui.control.NumericEditField
        ShowPeaksCheckBox              matlab.ui.control.CheckBox
        FindPeakSettingsPanel          matlab.ui.container.Panel
        IntervalEditFieldLabel         matlab.ui.control.Label
        IntervalEditField              matlab.ui.control.NumericEditField
        PeakThresholdNEditFieldLabel   matlab.ui.control.Label
        PeakThresholdNEditField        matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel        matlab.ui.control.Label
        ToleranceEditField             matlab.ui.control.NumericEditField
        NormierenButton                matlab.ui.control.StateButton
        AllgemeineEinstellungenPanel   matlab.ui.container.Panel
        SegmentHeaderLengthDropDownLabel  matlab.ui.control.Label
        SegmentHeaderLengthDropDown    matlab.ui.control.DropDown
        CurveSegmentstoPlotDropDownLabel  matlab.ui.control.Label
        CurveSegmentstoPlotDropDown    matlab.ui.control.DropDown
    end


    properties (Access = public)
        Channels = struct() % Alle AFM-Channel, die Exportiert werden können
        Units = struct() % Einheiten der möglichen AFM-Channel
        Names = struct() % Fancy-Names der möglichen AFM-Channel
        OldFolder = [] % Pfad des bereits einmal geöffneten Ordner
        DataFolder = [] % Pfad eines neu geöffneten Ordners
        Remembered = [] % Pfad des gespeicherten Ordneres
        Curves = struct() % Container für alle geladenen Kurvendaten
        FileNames = [] % Liste aller geladenen Kurven (curvename.txt)
        FieldCurveNames = [] % Liste aller Kurven (curvename)
        Labels = [] % Liste aller anzuzeigender Labels
        LabelHandles = [] % Liste aller aktiven Label-handles
        StartSegment = [] % Startindex ab welchem die Kraftkurve geplottet wird
        StopSegment = [] % Stopindes bis zu welchem die Kraftkurve geplottet wird
        OldXVec = struct() % xvector vor normierung von Overlay 1
        PeakPositions = [] % Positionen der gefundenen Peaks entlang aller geplotteten Kraftkurven in Overlay1
    end

    methods (Access = public)
    
        function ImportData(app)
            
            %Testdaten


            % get directory contents
            directory = dir(app.DataFolder);
            directory = struct2table(directory);
            
            % discard directorys
            files = directory.name;
            mask = directory.isdir == 0;
            files = files(mask);
            
            % select only .txt-files
            ext = split(files, '.');
            ext = ext(:,2);
            mask = ismember(ext, 'txt');
            files = files(mask);
            
            app.FileNames = files;
            
            % Daten import
            disp('Perparing Testdata:');
            bar = waitbar(0,'Preparing Testdata...');
            steps = length(app.FileNames);
            app.Curves = struct();
            for i = 1:steps
                disp(app.FileNames{i});
                file = [app.DataFolder '\' app.FileNames{i}];
                curve = afm(file);
                curve.SegmentHeaderLength = app.SegmentHeaderLengthDropDown.Value;
                curve = curve.ImportData();
                curve = curve.StartSearch();
                try
                    app.Curves.(curve.FileName) = curve;
                    assignin('base','ForceCurves',app.Curves);
                catch ME % if you can
                    switch ME.identifier
                        case 'MATLAB:AddField:InvalidFieldName'
                            warning(ME.identifier,...
                                    '%s\n%s >> skipping Curve',...
                                    ME.identifier,ME.message);
                            continue
                        otherwise
                            rethrow(ME);
                    end
                end
                waitbar(i/steps);
            end
            disp('Done');
            close(bar);
            
            PreparePlotData(app);
            
        end % function ImportData()
        
        function UpdateCurveOverlay(app,OverlayName,varargin)
            
            % Optionale Parameter
            p = inputParser;
            addRequired(p,'app');
            addRequired(p,'OverlayName');
            addParameter(p,'update_segments',0);
            addParameter(p,'SingleLine',[]);
            addParameter(p,'xchannel',[]);
            addParameter(p,'ychannel',[]);
            addParameter(p,'xvec',[]);
            addParameter(p,'yvec',[]);
            parse(p,app,OverlayName,varargin{:})

            overlay = findobj('type','figure','name',OverlayName);
            ax = findobj(overlay,'type','axes');
            if isempty(p.Results.SingleLine)
                LineObjects = findall(overlay,'type','line');
            else
                LineObjects = p.Results.SingleLine;
            end
            
            % Update der angezeigten Kurventeile
            if p.Results.update_segments == 1
                if ~isempty(overlay)
                    % try to give an hint that "Kraftkurven" trys do close
                    % the overlay
                    h = overlay.CloseRequestFcn;
                    if strcmp(h, 'closereq')
                        close(overlay);
                    else
                        event_data.EventSource = app;
                        event_data.EventSourceName = class(app);
                        event_data.EventName = 'CloseReq';
                        h(overlay, event_data)
                    end
                    if ~isempty(app.Curves)
                        PreparePlotData(app);
                        PlotCurveOverlay(app,OverlayName,p.Results.xchannel,p.Results.ychannel);
                    end
                    overlay = findobj('type','figure','name',OverlayName);
                    ax = findobj(overlay,'type','axes');
                end
            end
            
            %% Optionale Parameter
            % Update figure
            if ~isempty(p.Results.xchannel) 
                ax.XLabel.String = [app.Names.(p.Results.xchannel) ' / ' app.Units.(p.Results.xchannel)];
            end
            
            if ~isempty(p.Results.ychannel)
                ax.YLabel.String = [app.Names.(p.Results.ychannel) ' / ' app.Units.(p.Results.ychannel)];
            end
            
            % Update Line Objects
            if ~isempty(p.Results.xvec)
                for i = 1:length(LineObjects)
                    line = LineObjects(i,1);
                    line.XData = p.Results.xvec;
                end
            end
            
            if ~isempty(p.Results.yvec)
                for i = 1:length(LineObjects)
                    line = LineObjects(i,1);
                    line.XData = p.Results.yvec;
                end
            end
            
        end % UpdateCurveOverlay
        
        function PreparePlotData(app)
            
            app.FieldCurveNames = fieldnames(app.Curves);
            app.Labels = cell(length(app.FieldCurveNames),1);
            app.LabelHandles = zeros(length(app.FieldCurveNames),1);
            
            % if no curve has been loaded yet: abort function
            if isempty(fieldnames(app.Curves))
                return
            end
            
            % Automatische Erfassung von trace und retrace Elementen
            segments = fieldnames(app.Curves.(app.FieldCurveNames{1,1}).CurveData);
            SegType = cell(length(segments),1);
            SegIdx = zeros(length(segments),1);
            curve_names = fieldnames(app.Curves);
            for i = 1:length(app.Names) % iteration durch afm-kurven
                info = app.Curves.(curve_names{i}).SegmentInformation;
                for n = 1:length(info) % iteration durch Segmentinformation
                    for m = 1:length(info{n})
                        line = info{n}{m};
                        if contains(line,'segment:')
                            split_line = split(line,': ');
                            type = split_line{2,1};
                            break
                        end
                    end
                    SegType{n} = type;
                end
            end
            switch app.CurveSegmentstoPlotDropDown.Value
                case 'both'
                    for i = 1:length(SegType)
                        SegIdx(i,1) = i;
                    end
                    app.StartSegment = min(SegIdx);
                    app.StopSegment = max(SegIdx);
                case 'trace'
                    for i = 1:length(SegType)
                        if strcmp(SegType{i,1},'extend')
                            SegIdx(i,1) = i;
                        end
                    end
                    SegIdx = find(SegIdx);
                    app.StartSegment = min(SegIdx);
                    app.StopSegment = max(SegIdx);
                case 'retrace'
                    for i = 1:length(SegType)
                        if strcmp(SegType{i,1},'retract')
                            SegIdx(i,1) = i;
                        end
                    end
                    SegIdx = find(SegIdx);
                    app.StartSegment = min(SegIdx);
                    app.StopSegment = max(SegIdx);
            end
        end % PreparePlotData
        
        function PlotCurveOverlay(app,OverlayName,xchannel,ychannel)
            if isempty(findobj('type','figure','name',OverlayName))
                
                % prepare figure
                fig = figure('NumberTitle','off');
                ax = axes(fig);
                fig.Name = OverlayName;
                fig.Units = 'centimeters';
                fig.OuterPosition = [1 1 20 20];
                ax.FontSize = 30;
                ax.SortMethod = 'childorder';
                ax.XLabel.String = [app.Names.(xchannel) ' / ' app.Units.(xchannel)];
                ax.YLabel.String = [app.Names.(ychannel) ' / ' app.Units.(ychannel)];
                grid on;
                grid minor;
                plottools;
            else
                fig = findobj('type','figure','name',OverlayName);
                ax = findobj(fig, 'type', 'axes');
            end % isempty(findobj('type','figure','name','Overlay 1'))
            
            % plot of force-curves
            for i = 1:length(app.FieldCurveNames)
                x = [];
                y = [];
                if isempty(app.Curves.(app.FieldCurveNames{i,1}).CurveData)
                    error('\nCurveData of curve "%s" is empty\n%s\n%s\n%s',...
                            app.FieldNames{i,1},...
                            '>> possibly wrong SegmentHeaderLength',...
                            '>> not sure if "*.txt"-file has been read correctly',...
                            '>> must exit');
                else
                    for n=app.StartSegment:app.StopSegment
                        
                        % testen, ob alle Segmente vorhanden sind
                        try
                            dx = app.Curves.(app.FieldCurveNames{i,1}).CurveData.(['Segment' num2str(n)]).(app.Channels.(xchannel));
                        catch ME % if you can
                            switch ME.identifier
                                case 'MATLAB:nonExistentField'
                                    dx = [];
                                otherwise
                                    rethrow(ME);
                            end
                        end
                        try
                            dy = app.Curves.(app.FieldCurveNames{i,1}).CurveData.(['Segment' num2str(n)]).(app.Channels.(ychannel));
                        catch ME % if you can
                            switch ME.identifier
                                case 'MATLAB:nonExistentField'
                                    dy = [];
                                otherwise
                                    rethrow(ME);
                            end
                        end
                        
                        % fehlende Segmente mit NaN's auffüllen
                        lx = length(dx);
                        ly = length(dy);
                        if lx ~= ly
                            if lx < ly
                                dl = ly-lx;
                                nan = NaN(dl,1);
                                dx = [dx;nan];
                            else
                                dl = lx - ly;
                                nan = NaN(dl,1);
                                dy = [dy;nan];
                            end
                        end
                        
                        % erstelle x,y vektoren zum plotten der daten
                        x = vertcat(x,dx);
                        y = vertcat(y,dy);
                        
                    end
                    hold(ax, 'on');
                    line = scatter(ax,x,y,'ButtonDownFcn',@MouseEventCallback);      
                    line.SizeData = 30;
                    line.Marker = '.';
                    app.Labels{i} = app.FieldCurveNames{i}; 
                    hold(ax, 'off')
                    
                    try % für den fall dass es das gewünschte Kurvensegment nicht gibt ist line leer
                        app.LabelHandles(i) = line;
                    catch ME % if you can
                        switch ME.identifier
                            case 'MATLAB:matrix:singleSubscriptNumelMismatch' % Fehler für leeres line-Object
                                app.Labels(i) = []; 
                                app.LabelHandles(i) = [];
                                warning(ME.identifier,...
                                        '%s \n %s \n >> %s: Das zu plottende Segment war leer \n >> skip segment',...
                                        ME.message,...
                                        ME.identifier,...
                                        app.FieldCurveNames{i});
                            otherwise
                                rethrow(ME);
                        end
                    end
                end % isempty(curves.(app.FieldCurveNames{i,1}).CurveData)
            end % for i = 1:length(app.FieldCurveName)
            legend(ax, app.LabelHandles,app.Labels);
            
        end % PlotCurveOverlay
        
        function ListCurves(app)
            frame = figure('Visible','off',...
                           'NumberTitle','off',...
                           'Name','Geladene Kraftkurven',...
                           'MenuBar','none',...
                           'Toolbar','none',...
                           'Units','normalized',...
                           'Position',[0.1,0.5,0.3,0.4]);
                       
            CurveList = uicontrol('Style','listbox');
            CurveList.Max = length(app.FieldCurveNames);
            CurveList.Min = 1;
            CurveList.String = PopulateList();
            CurveList.Units = 'normalized';
            CurveList.Position = [0.01,0.01,0.4,0.98];
                              
            Label = uicontrol('Style','text');
            Label.String = 'Kurven speichern in:';
            Label.HorizontalAlignment = 'left';
            Label.Units = 'normalized';
            Label.Position = [0.45,0.95,0.54,0.04];
            
            SavePath = uicontrol('Style','edit');
            SavePath.String = [];
            SavePath.HorizontalAlignment = 'left';
            SavePath.Units = 'normalized';
            SavePath.Position = [0.45,0.88,0.54,0.07];
            
            BrowseButton = uicontrol('Style','pushbutton');
            BrowseButton.String = 'Durchsuchen';
            BrowseButton.Units = 'normalized';
            BrowseButton.Position = [0.79,0.8,0.2,0.07];
            BrowseButton.Callback = @BrowseButtonCallback;
            
            SaveButton = uicontrol('Style','pushbutton');
            SaveButton.String = 'Speichern';
            SaveButton.Units = 'normalized';
            SaveButton.Position = [0.79,0.01,0.2,0.07];
            SaveButton.Callback = @SaveButtonCallback;
            
            frame.Visible = 'on';
            
            function names = PopulateList()
                files = dir([app.Remembered '\*.txt']);
                names = {files.name};
            end % PopulateList
            
            function BrowseButtonCallback(~,~)
                SavePath.String = uigetdir(app.Remembered);
            end % BrowseButtonCallback
        
            function SaveButtonCallback(~,~)
                if isempty(SavePath.String)
                    app.SavePath.String = uigetdir(app.Remembered);
                end
                names = PopulateList();
                for i = 1:length(CurveList.Value)
                    idx = CurveList.Value(i);
                    origin = [app.Remembered '\' names{1,idx}];
                    saveto = [SavePath.String '\' names{1,idx}];
                    copyfile(origin,saveto)
                end
                
            end % SaveButtonCallback
            
        end % ListCurves


    end % methods (Access = public)

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Channel Auswahlmöglichkeiten
            app.Channels = struct('vertical_tip_position','verticalTipPosition',...
                              'vertical_deflection','vDeflection',...
                              'head_height_measured','strainGaugeHeight',...
                              'height','height',...
                              'error_signal','error',...
                              'head_height','head_height',...
                              'head_height_measured_smoothed','smoothedStrainGaugeHeight',...
                              'height_measured_smoothed','smoothedMeasuredHeight',...
                              'height_measured','measuredHeight',...
                              'lateral_deflection','hDeflection',...
                              'series_time','seriesTime',...
                              'segment_time','time');
                          
            app.Units = struct('vertical_tip_position','m',...
                              'vertical_deflection','N',...
                              'head_height_measured','m',...
                              'height','m',...
                              'error_signal','V',...
                              'head_height','m',...
                              'head_height_measured_smoothed','m',...
                              'height_measured_smoothed','m',...
                              'height_measured','m',...
                              'lateral_deflection','V',...
                              'series_time','s',...
                              'segment_time','s');
                       
            app.Names = struct('vertical_tip_position','Vertical Tip Position',...
                              'vertical_deflection','Vertical Deflection',...
                              'head_height_measured','Head Height (measured)',...
                              'height','Height',...
                              'error_signal','Error Signal',...
                              'head_height','Head Height',...
                              'head_height_measured_smoothed','Head Height (measured & smoothed)',...
                              'height_measured_smoothed','Height (measured & smoothed)',...
                              'height_measured','Height (measured)',...
                              'lateral_deflection','Lateral Deflection',...
                              'series_time','Series Time',...
                              'segment_time','Time');
            app.XChannelDropDown_1.Items = fieldnames(app.Channels);
            app.YChannelDropDown_1.Items = fieldnames(app.Channels);
            app.YChannelDropDown_1.Value = 'vertical_deflection';
            
            app.XChannelDropDown_2.Items = fieldnames(app.Channels);
            app.YChannelDropDown_2.Items = fieldnames(app.Channels);
            app.YChannelDropDown_2.Value = 'vertical_deflection';
            
            app.XChannelDropDown_3.Items = fieldnames(app.Channels);
            app.YChannelDropDown_3.Items = fieldnames(app.Channels);
            app.YChannelDropDown_3.Value = 'vertical_deflection';
            
            % SegmentHeaderLength
            app.SegmentHeaderLengthDropDown.Items = {'jpk_fc_full_settings'};
            
            % CurveSegmentsToPlot
            app.CurveSegmentstoPlotDropDown.Items = {'both','trace','retrace'};
        end

        % Button pushed function: LadenButton
        function LadenButtonPushed(app, event)
            %% Ordnerinformationen
                
            folder = uigetdir(app.Remembered);
            if folder ~= 0
                app.Remembered = folder;
                app.DataFolder = folder;
                app.OldFolder = folder;
            else
                return
            end
             
            app.LoadCurvesEditField.Value = app.Remembered;
            ImportData(app);
            
            PlotCurveOverlay(app,'Overlay_1',app.XChannelDropDown_1.Value,app.YChannelDropDown_1.Value);
        end

        % Button pushed function: KurvenlisteButton
        function KurvenlisteButtonPushed(app, event)
            ListCurves(app);
        end

        % Value changed function: CurveSegmentstoPlotDropDown
        function CurveSegmentstoPlotDropDownValueChanged(app, event)
            % finde Overlays
            Figures = findall(groot,'type','figure');
            mask = Figures ~= findobj(Figures,'Name',app.KraftkurvenUIFigure.Name);
            Overlays = Figures(mask);
            
            % omit all overlays with UserData.EditRequest == false
            mask = true(length(Overlays), 1);
            for i = 1:length(Overlays)
                overlay = Overlays(i);
                if isfield(overlay.UserData, 'EditRequest')
                    if overlay.UserData.EditRequest == false
                        mask(i,1) = false;
                    end
                end
            end
            Overlays = Overlays(mask); 
            
            if ~isempty(Overlays)
                for i = 1:length(Overlays)
                    OverlayName = Overlays(i).Name;
                    DropDownX = ['XChannelDropDown_' num2str(i)];
                    DropDownY = ['YChannelDropDown_' num2str(i)];
                    xchannel = app.(DropDownX).Value;
                    ychannel = app.(DropDownY).Value;       
                    UpdateCurveOverlay(app,OverlayName,'update_segments',1,'xchannel',xchannel,'ychannel',ychannel);
                end
            end
        end

        % Value changed function: NormierenButton
        function NormierenButtonValueChanged(app, event)
            value = app.NormierenButton.Value;
            
            overlay = findobj('Name', 'Overlay_1');
            OverlayName = overlay.Name;
            ax = findobj(overlay,'type','axes');
            LineObjects = findall(ax,'type','scatter');
            peak_num = app.PeakNumberofInterestEditField.Value;
            norm_force = app.NormValueNEditField.Value;
            interval = app.IntervalEditField.Value;
            tolerance = app.ToleranceEditField.Value;
            peak_thresh = app.PeakThresholdNEditField.Value;

            if value == 1
                app.OldXVec.(OverlayName) = cell(length(LineObjects),1);
                for i = 1:length(LineObjects)
                    x = LineObjects(i,1).XData;
                    y = LineObjects(i,1).YData;
                    app.OldXVec.(OverlayName){i,1} = x;
                    
                    position_name = LineObjects(i,1).DisplayName;
                    if isfield(app.PeakPositions,position_name)
                        pos = app.PeakPositions.(position_name);
                    else
                        pos = [];
                    end
                    
                    index = index_of_best_fit(x,y,norm_force,peak_num,interval,tolerance,peak_thresh,'PeakPositions',pos);
                    % Falls index_of_best_fit keinen Peak oder die ref_force nicht
                    % findet wird NaN zurückgegeben
                    if ~isnan(index)
                        if x(index) > 0
                            norm_length = x(index);
                        else
                            warning('index_of_best_fit: norm_length negative >> nomring with absolut value');
                            norm_length = x(index)*(-1);
                        end
                    else
                        norm_length = 1;
                    end
                    x_norm = (x/norm_length);
                    PreparePlotData(app);
                    UpdateCurveOverlay(app,OverlayName,'update_segments',0,'SingleLine',LineObjects(i,1),'xvec',x_norm);
                end % for i = 1:length(LineObjects)
            else
                for i = 1:length(LineObjects)
                    PreparePlotData(app);
                    UpdateCurveOverlay(app,OverlayName,'update_segments',0,'SingleLine',LineObjects(i,1),'xvec',app.OldXVec.(OverlayName){i,1});
                end
            end
        end

        % Value changed function: ShowPeaksCheckBox
        function ShowPeaksCheckBoxValueChanged(app, event)
            value = app.ShowPeaksCheckBox.Value;
            peak_thresh = app.PeakThresholdNEditField.Value;
            overlay = findobj('Name', 'Overlay_1');
            ax = findobj(overlay,'type','axes');
            LineObjects = findall(ax,'type','scatter');
            
            if value == 1
                app.PeakPositions = [];
                for i = 1:length(LineObjects)
                    [~,mintab] = peakdet(LineObjects(i,1).YData,peak_thresh,LineObjects(i,1).XData); 
                    
                    if ~isempty(mintab)
                        position_name = ['PeakPosition-' LineObjects(i,1).DisplayName];
                        hold on
                        sc = scatter(ax,mintab(:,1),mintab(:,2),'ro','LineWidth',2,'DisplayName',position_name);
                        sc.Tag = 'peak_positions';
                        sc.ButtonDownFcn = {@DeleteSpecificPeakPositionCallback,app};
                        hold off
                        app.PeakPositions.(LineObjects(i,1).DisplayName) = mintab;
                    end
                end
            else
                peaks = findall(overlay,'type','scatter', 'Tag', 'peak_positions');
                for i = 1:length(peaks)
                    delete(peaks(i));
                end
            end
        end

        % Value changed function: XChannelDropDown_1
        function XChannelDropDown_1ValueChanged(app, event)
            xchannel = app.XChannelDropDown_1.Value;
            ychannel = app.YChannelDropDown_1.Value;
            UpdateCurveOverlay(app,'Overlay_1','update_segment',1,'xchannel',xchannel,'ychannel',ychannel)
        end

        % Value changed function: YChannelDropDown_1
        function YChannelDropDown_1ValueChanged(app, event)
            xchannel = app.XChannelDropDown_1.Value;
            ychannel = app.YChannelDropDown_1.Value;
            label_text = sprintf('Peak Threshold [%s]',app.Units.(ychannel));
            app.PeakThresholdNEditFieldLabel.Text = label_text;
            label_text = sprintf('Norm Value [%s]',app.Units.(ychannel));
            app.NormValueNEditFieldLabel.Text = label_text;
            UpdateCurveOverlay(app,'Overlay_1','update_segment',1,'xchannel',xchannel,'ychannel',ychannel)
        end

        % Value changed function: aktivierenButton
        function aktivierenButtonValueChanged(app, event)
            value = app.aktivierenButton.Value;
            if value == 1
                app.XChannelDropDown_2.Enable = 'on';
                app.YChannelDropDown_2.Enable = 'on';
                xchannel = app.XChannelDropDown_2.Value;
                ychannel = app.YChannelDropDown_2.Value;
                PreparePlotData(app);
                PlotCurveOverlay(app,'Overlay_2',xchannel,ychannel);
            else
                app.XChannelDropDown_2.Enable = 'off';
                app.YChannelDropDown_2.Enable = 'off';
                overlay = findobj('type','figure','name','Overlay_2');
                close(overlay);
            end
        end

        % Value changed function: aktivierenButton_2
        function aktivierenButton_2ValueChanged(app, event)
            value = app.aktivierenButton_2.Value;
            if value == 1
                app.XChannelDropDown_3.Enable = 'on';
                app.YChannelDropDown_3.Enable = 'on';
                xchannel = app.XChannelDropDown_3.Value;
                ychannel = app.YChannelDropDown_3.Value;
                PreparePlotData(app);
                PlotCurveOverlay(app,'Overlay_3',xchannel,ychannel);
            else
                app.XChannelDropDown_3.Enable = 'off';
                app.YChannelDropDown_3.Enable = 'off';
                overlay = findobj('type','figure','name','Overlay_3');
                close(overlay);
            end
        end

        % Value changed function: XChannelDropDown_2
        function XChannelDropDown_2ValueChanged(app, event)
            xchannel = app.XChannelDropDown_2.Value;
            ychannel = app.YChannelDropDown_2.Value;
            app.PeakThresholdNEditFieldLabel.Text = app.Names.(xchannel);
            UpdateCurveOverlay(app,'Overlay_2','update_segment',1,'xchannel',xchannel,'ychannel',ychannel)
        end

        % Value changed function: YChannelDropDown_2
        function YChannelDropDown_2ValueChanged(app, event)
            xchannel = app.XChannelDropDown_2.Value;
            ychannel = app.YChannelDropDown_2.Value;
            UpdateCurveOverlay(app,'Overlay_2','update_segment',1,'xchannel',xchannel,'ychannel',ychannel)
        end

        % Value changed function: XChannelDropDown_3
        function XChannelDropDown_3ValueChanged(app, event)
            xchannel = app.XChannelDropDown_3.Value;
            ychannel = app.YChannelDropDown_3.Value;
            app.PeakThresholdNEditFieldLabel.Text = app.Names.(xchannel);
            UpdateCurveOverlay(app,'Overlay_3','update_segment',1,'xchannel',xchannel,'ychannel',ychannel)
        end

        % Value changed function: YChannelDropDown_3
        function YChannelDropDown_3ValueChanged(app, event)
            xchannel = app.XChannelDropDown_3.Value;
            ychannel = app.YChannelDropDown_3.Value;
            UpdateCurveOverlay(app,'Overlay_3','update_segment',1,'xchannel',xchannel,'ychannel',ychannel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KraftkurvenUIFigure = uifigure('Visible', 'off');
            app.KraftkurvenUIFigure.Name = 'Kraftkurven';
            app.AbbildungenPanel = uipanel(app.KraftkurvenUIFigure);
            app.AbbildungenPanel.Title = 'Abbildungen';
            app.Overlay1Panel = uipanel(app.AbbildungenPanel);
            app.Overlay1Panel.Title = 'Overlay 1';
            app.XChannelDropDownLabel = uilabel(app.Overlay1Panel);
            app.XChannelDropDownLabel.Text = 'X-Channel';
            app.XChannelDropDown_1 = uidropdown(app.Overlay1Panel);
            app.XChannelDropDown_1.Items = {};
            app.XChannelDropDown_1.ValueChangedFcn = createCallbackFcn(app, @XChannelDropDown_1ValueChanged, true);
            app.XChannelDropDown_1.Value = {};
            app.YChannelDropDownLabel = uilabel(app.Overlay1Panel);
            app.YChannelDropDownLabel.Text = 'Y-Channel';
            app.YChannelDropDown_1 = uidropdown(app.Overlay1Panel);
            app.YChannelDropDown_1.Items = {};
            app.YChannelDropDown_1.ValueChangedFcn = createCallbackFcn(app, @YChannelDropDown_1ValueChanged, true);
            app.YChannelDropDown_1.Value = {};
            app.Overlay2Panel = uipanel(app.AbbildungenPanel);
            app.Overlay2Panel.Title = 'Overlay 2';
            app.XChannelDropDown_2Label = uilabel(app.Overlay2Panel);
            app.XChannelDropDown_2Label.Text = 'X-Channel';
            app.XChannelDropDown_2 = uidropdown(app.Overlay2Panel);
            app.XChannelDropDown_2.Items = {};
            app.XChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @XChannelDropDown_2ValueChanged, true);
            app.XChannelDropDown_2.Enable = 'off';
            app.XChannelDropDown_2.Value = {};
            app.YChannelDropDown_2Label = uilabel(app.Overlay2Panel);
            app.YChannelDropDown_2Label.Enable = 'off';
            app.YChannelDropDown_2Label.Text = 'Y-Channel';
            app.YChannelDropDown_2 = uidropdown(app.Overlay2Panel);
            app.YChannelDropDown_2.Items = {};
            app.YChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @YChannelDropDown_2ValueChanged, true);
            app.YChannelDropDown_2.Enable = 'off';
            app.YChannelDropDown_2.Value = {};
            app.Overlay3Panel = uipanel(app.AbbildungenPanel);
            app.Overlay3Panel.Title = 'Overlay 3';
            app.XChannelDropDown_3Label = uilabel(app.Overlay3Panel);
            app.XChannelDropDown_3Label.Text = 'X-Channel';
            app.XChannelDropDown_3 = uidropdown(app.Overlay3Panel);
            app.XChannelDropDown_3.Items = {};
            app.XChannelDropDown_3.ValueChangedFcn = createCallbackFcn(app, @XChannelDropDown_3ValueChanged, true);
            app.XChannelDropDown_3.Enable = 'off';
            app.XChannelDropDown_3.Value = {};
            app.YChannelDropDown_3Label = uilabel(app.Overlay3Panel);
            app.YChannelDropDown_3Label.Text = 'Y-Channel';
            app.YChannelDropDown_3 = uidropdown(app.Overlay3Panel);
            app.YChannelDropDown_3.Items = {};
            app.YChannelDropDown_3.ValueChangedFcn = createCallbackFcn(app, @YChannelDropDown_3ValueChanged, true);
            app.YChannelDropDown_3.Enable = 'off';
            app.YChannelDropDown_3.Value = {};
            app.aktivierenButton = uibutton(app.AbbildungenPanel, 'state');
            app.aktivierenButton.ValueChangedFcn = createCallbackFcn(app, @aktivierenButtonValueChanged, true);
            app.aktivierenButton.Text = 'aktivieren';
            app.aktivierenButton_2 = uibutton(app.AbbildungenPanel, 'state');
            app.aktivierenButton_2.ValueChangedFcn = createCallbackFcn(app, @aktivierenButton_2ValueChanged, true);
            app.aktivierenButton_2.Text = 'aktivieren';
            app.LadeKraftkurvenPanel = uipanel(app.KraftkurvenUIFigure);
            app.LadeKraftkurvenPanel.Title = 'Lade Kraftkurven';
            app.LoadCurvesEditField = uieditfield(app.LadeKraftkurvenPanel, 'text');
            app.LadenButton = uibutton(app.LadeKraftkurvenPanel, 'push');
            app.LadenButton.ButtonPushedFcn = createCallbackFcn(app, @LadenButtonPushed, true);
            app.LadenButton.Text = 'Laden';
            app.KurvenlisteButton = uibutton(app.LadeKraftkurvenPanel, 'push');
            app.KurvenlisteButton.ButtonPushedFcn = createCallbackFcn(app, @KurvenlisteButtonPushed, true);
            app.KurvenlisteButton.Text = 'Kurvenliste';
            app.EinstellungenNormierungPanel = uipanel(app.KraftkurvenUIFigure);
            app.EinstellungenNormierungPanel.Title = 'Einstellungen Normierung';
            app.NormSettingsPanel = uipanel(app.EinstellungenNormierungPanel);
            app.NormSettingsPanel.Title = 'Norm Settings';
            app.NormValueNEditFieldLabel = uilabel(app.NormSettingsPanel);
            app.NormValueNEditFieldLabel.Text = 'Norm Value [N]';
            app.NormValueNEditField = uieditfield(app.NormSettingsPanel, 'numeric');
            app.NormValueNEditField.ValueDisplayFormat = '%e';
            app.NormValueNEditField.Value = -6e-12;
            app.PeakNumberofInterestEditFieldLabel = uilabel(app.NormSettingsPanel);
            app.PeakNumberofInterestEditFieldLabel.Text = 'Peak Number of Interest';
            app.PeakNumberofInterestEditField = uieditfield(app.NormSettingsPanel, 'numeric');
            app.ShowPeaksCheckBox = uicheckbox(app.NormSettingsPanel);
            app.ShowPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPeaksCheckBoxValueChanged, true);
            app.ShowPeaksCheckBox.Text = 'Show Peaks';
            app.FindPeakSettingsPanel = uipanel(app.EinstellungenNormierungPanel);
            app.FindPeakSettingsPanel.Title = 'Find Peak Settings';
            app.IntervalEditFieldLabel = uilabel(app.FindPeakSettingsPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.FindPeakSettingsPanel, 'numeric');
            app.IntervalEditField.ValueDisplayFormat = '%.0f';
            app.IntervalEditField.Value = 1;
            app.PeakThresholdNEditFieldLabel = uilabel(app.FindPeakSettingsPanel);
            app.PeakThresholdNEditFieldLabel.Text = 'Peak Threshold [N]';
            app.PeakThresholdNEditField = uieditfield(app.FindPeakSettingsPanel, 'numeric');
            app.PeakThresholdNEditField.ValueDisplayFormat = '%E';
            app.PeakThresholdNEditField.Value = 1e-09;
            app.ToleranceEditFieldLabel = uilabel(app.FindPeakSettingsPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.FindPeakSettingsPanel, 'numeric');
            app.ToleranceEditField.ValueDisplayFormat = '%5.2f';
            app.ToleranceEditField.Value = 0.5;
            app.NormierenButton = uibutton(app.EinstellungenNormierungPanel, 'state');
            app.NormierenButton.ValueChangedFcn = createCallbackFcn(app, @NormierenButtonValueChanged, true);
            app.NormierenButton.Text = 'Normieren';
            app.AllgemeineEinstellungenPanel = uipanel(app.KraftkurvenUIFigure);
            app.AllgemeineEinstellungenPanel.Title = 'Allgemeine Einstellungen';
            app.SegmentHeaderLengthDropDownLabel = uilabel(app.AllgemeineEinstellungenPanel);
            app.SegmentHeaderLengthDropDownLabel.Text = 'Segment Header Length';
            app.SegmentHeaderLengthDropDown = uidropdown(app.AllgemeineEinstellungenPanel);
            app.SegmentHeaderLengthDropDown.Items = {};
            app.SegmentHeaderLengthDropDown.Value = {};
            app.CurveSegmentstoPlotDropDownLabel = uilabel(app.AllgemeineEinstellungenPanel);
            app.CurveSegmentstoPlotDropDownLabel.Text = 'Curve Segments to Plot';
            app.CurveSegmentstoPlotDropDown = uidropdown(app.AllgemeineEinstellungenPanel);
            app.CurveSegmentstoPlotDropDown.Items = {};
            app.CurveSegmentstoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @CurveSegmentstoPlotDropDownValueChanged, true);
            app.CurveSegmentstoPlotDropDown.Value = {};
            % Show the figure after all components are created
            app.KraftkurvenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kraftkurven

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KraftkurvenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KraftkurvenUIFigure)
        end
    end
end